classdef T1optimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TIoptimizationButton      matlab.ui.control.Button
        OptimizationmatrixButton  matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        MRIviewTab                matlab.ui.container.Tab
        MRI_UIAxes3               matlab.ui.control.UIAxes
        MRI_UIAxes2               matlab.ui.control.UIAxes
        MRI_UIAxes1               matlab.ui.control.UIAxes
        OptimizedT1Tab            matlab.ui.container.Tab
        T1tap_UIAxes              matlab.ui.control.UIAxes
        progressGauge             matlab.ui.control.LinearGauge
        progressGaugeLabel        matlab.ui.control.Label
        MRILoadButton             matlab.ui.control.Button
        T1FileLocationLabel       matlab.ui.control.Label
        SelectfileButton          matlab.ui.control.Button
        SegmentationButton        matlab.ui.control.Button
        MakeameshButton           matlab.ui.control.Button
        SolveButton               matlab.ui.control.Button
    end


    properties (Access = private)
        SelectedNiiFile
        SelectedNiiFileName
        FileNameWithoutExtension
        num_el
        reference
        node
        elem
        face
        hdrinfo
        electrode
        indElecSolve % Description
        E_all % Description
        E
        bb
    end

    properties (Access = public)
        UserData = struct() % Description: UserData at figure
    end


    methods (Access = private)


        %         function myCallback(app, fh,~)
        %             % This selects new position depending on which subplot a button is clicked.
        %             % Updates display information in mydata.
        %
        %             mydata = get(fh,'UserData');
        %             pos    = get(gca,'CurrentPoint');
        %             pos    = round(pos(1,1:2));
        %             switch gca
        %                 case mydata.h(1); mydata.pos([1 3]) = pos;
        %                 case mydata.h(2); mydata.pos([2 3]) = pos;
        %                 case mydata.h(3); mydata.pos([1 2]) = pos;
        %             end
        %
        %             % if new position is valid, update mydata as figure property and display
        %             % otherwise do nothing
        %             if ~sum( mydata.pos<1 | mydata.pos>size(mydata.img) )
        %                 set(fh,'UserData',mydata);
        %                 showimages(app)
        %             end
        %
        %         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            % select sensor coordinates file
            fileName = uigetfile('*.nii','Select a NIfTI file');
            if fileName ~= 0 % 사용자가 파일을 선택한 경우
                app.SelectedNiiFile = niftiread(fileName);
            end
            app.T1FileLocationLabel.Text = fileName;
            app.SelectedNiiFileName=fileName;
            app.FileNameWithoutExtension = fileName(1:end-4);

        end

        % Button pushed function: MRILoadButton
        function MRILoadButtonPushed(app, event)
            if isempty(app.SelectedNiiFile)
                errordlg('Please select a NIfTI file first.', 'Error');
                return;
            end
            T1Data = load_untouch_nii(app.SelectedNiiFileName); % call load_untouch_nii func

            app.electrode = '10_20';

            sliceshow_T1(app, T1Data.img,[],'gray',[],[]); drawnow
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)

            start_seg(app.SelectedNiiFileName,[]); %% app.SelectedNiiFileName가 있는 폴더에서 실행
            segTouchup(app.SelectedNiiFileName,[]); %% segmentation 된 이미지 보정

            disp('Segmentation Finished.');
        end

        % Button pushed function: MakeameshButton
        function MakeameshButtonPushed(app, event)
            app.electrode = '10_20';

            if isequal(app.electrode, '10_10')
                disp('electrode is 10_10');
                load('81options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=81;
                app.reference=-3;
            else
                load('18options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=18;
                app.reference=-3;
            end

            [app.node,app.elem,app.face] = meshByIso2mesh(app.SelectedNiiFileName,app.SelectedNiiFileName,[],options.meshOpt,app.hdrinfo,int2str(1)); %% mesh 생성
            disp('A Mesh is Maked');
        end

        % Button down function: MRI_UIAxes1
        function MRI_UIAxes1ButtonDown(app, event)
            disp('MRI_UIAxes1 tap pussed');


        end

        % Button down function: MRI_UIAxes2
        function MRI_UIAxes2ButtonDown(app, event)
            disp('MRI_UIAxes2 tap pussed');


        end

        % Button down function: MRI_UIAxes3
        function MRI_UIAxes3ButtonDown(app, event)
            disp('MRI_UIAxes3 tap pussed');


        end

        % Button down function: T1tap_UIAxes
        function T1tap_UIAxesButtonDown(app, event)
            disp('T1tap Axes pussed');


        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            prepareForGetDP(app.SelectedNiiFileName,app.node,app.elem,{'F3';'F4'},int2str(1),app.num_el); %% solve하기 전에 하는거

            if isequal(app.electrode, '10_10')
                app.indElecSolve = 1:app.num_el;
                temp=zeros(app.num_el,1);
                if ~exist([app.FileNameWithoutExtension '_' '81_e.pos'])
                    for i=1:81
                        temp=zeros(81,1);
                        temp(i)=1;temp(81+app.reference)=-1;
                        if i==81+app.reference
                        else
                            solveByGetDP(app.SelectedNiiFileName,temp,options.conductivities,app.indElecSolve,int2str(i),'');
                        end
                    end
                end
            else
                app.indElecSolve = 1:app.num_el;
                temp=zeros(app.num_el,1);
                if ~exist([app.FileNameWithoutExtension '_' '77_e.pos'])
                    for i=[1,3,14,16,18,20,22,36,38,40,42,44,56,58,60,62,64,77]
                        temp=zeros(81,1);
                        temp(i)=1;temp(81+app.reference)=-1;
                        if i==81+app.reference
                        else
                            solveByGetDP(app.SelectedNiiFileName,temp,options.conductivities,app.indElecSolve,int2str(i),'');
                        end
                    end
                end
            end

        end

        % Button pushed function: OptimizationmatrixButton
        function OptimizationmatrixButtonPushed(app, event)

            if isequal(app.electrode, '10_10') % 이 부분 check up 하기
                app.E_all={};
                tt=0;
                for i=1:81
                    if i==81+app.reference
                    else
                        tt=tt+1;
                        temp_name=[app.FileNameWithoutExtension '_' int2str(i) '_e.pos'];
                        app.E_all{tt}=dlmread(temp_name);
                    end
                end
                app.bb = load('opt_matrix81.mat').bb;
            else
                disp('start make an optimization matrix');
                app.E_all={};
                tt=0;
                for i=[1,3,14,16,18,20,22,36,38,40,42,44,56,58,60,62,64,77]
                    if i==81+app.reference
                    else
                        tt=tt+1;
                        temp_name=[app.FileNameWithoutExtension '_' int2str(i) '_e.pos'];
                        app.E_all{tt}=dlmread(temp_name);
                    end
                end
                app.bb = load('opt_matrix18.mat').bb;
                % disp(type(app.bb));

            end
            disp('finish making an optimization matrix.');

        end

        % Button pushed function: TIoptimizationButton
        function TIoptimizationButtonPushed(app, event)

            disp('start T1 optimization.');
            
            layer=1; %%
            brain=unique(app.elem(app.elem(:,5)==layer,1:4));
            target=[137.1 141 148.5]; %% target1=52.49,103.5,168; target2=52.5 67.24 154.8; target3=94.12 114.5 134.2
            %%51.37 152.1 142.1

            nl=find(abs(app.node(brain,1)-target(1))<3&abs(app.node(brain,2)-target(2))<3&abs(app.node(brain,3)-target(3))<3);
            tic
            E_temp=zeros(length(app.bb),9);
            E_dot=zeros(length(app.bb),1);
            for i=1:length(app.bb)

                E1=app.E_all{1,app.bb(i,1)}(2:end,2:end)-app.E_all{1,app.bb(i,2)}(2:end,2:end);
                E2=app.E_all{1,app.bb(i,3)}(2:end,2:end)-app.E_all{1,app.bb(i,4)}(2:end,2:end);

                app.E=[];

                E_dot(i)=sum(E1(brain(nl),1).*E2(brain(nl),1)+E1(brain(nl),2).*E2(brain(nl),2)...
                    +E1(brain(nl),3).*E2(brain(nl),3));
            end
            b1=find(E_dot==max(E_dot,[],'all'));

            disp('before for k=1:9');
            for k=1:9

                E1=app.E_all{1,app.bb(b1,1)}(2:end,2:end)-app.E_all{1,app.bb(b1,2)}(2:end,2:end);
                E2=app.E_all{1,app.bb(b1,3)}(2:end,2:end)-app.E_all{1,app.bb(b1,4)}(2:end,2:end);

                app.E=[];

                for j=1:length(nl)
                    app.E(j)=calc_TI(0.2*k*E1(brain(nl(j)),:),(2-0.2*k)*E2(brain(nl(j)),:));
                end
                E_temp(k)=sum(app.E);
            end

            toc
            disp('tictoc finish');
            [a1]=find(E_temp==max(E_temp,[],'all'));

            % optldx=3;

            optldx=b1;
            E1=app.E_all{1,app.bb(optldx,1)}(2:end,2:end)-app.E_all{1,app.bb(optldx,2)}(2:end,2:end);
            E2=app.E_all{1,app.bb(optldx,3)}(2:end,2:end)-app.E_all{1,app.bb(optldx,4)}(2:end,2:end);
            app.E=[];
            for j=1:length(app.node)
                app.E(j)=calc_TI(0.2*a1*E1(j),(2-0.2*a1)*E2(j));
            end
            
            disp('completed T1 optimization.');

            %% optimization result plotting

            plotsurf(app, [app.node(:,1:3),app.E'],app.face(app.face(:,4)==1,1:4),'EdgeColor','None');
            caxis(app.T1tap_UIAxes, [0 0.3]);

            light(app.T1tap_UIAxes, "Style","local","Position",[200 0 0]);
            light(app.T1tap_UIAxes, "Style","local","Position",[-200 0 0]);
            light(app.T1tap_UIAxes, "Style","local","Position",[0 200 0]);
            light(app.T1tap_UIAxes, "Style","infinite","Position",[0 0 200]);
            view(app.T1tap_UIAxes, [-1 1 1])
            axis(app.T1tap_UIAxes,'off');
%             set(app.T1tap_UIAxes, 'Position', [-0.25, -0.2, 1.5, 1.5]); % 이 부분 연동 확인하기
%             set(app.UIFigure,'BackgroundColor','w'); % Figure 배경 white로 바뀌는지 확인하기


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.MakeameshButton = uibutton(app.UIFigure, 'push');
            app.MakeameshButton.ButtonPushedFcn = createCallbackFcn(app, @MakeameshButtonPushed, true);
            app.MakeameshButton.Text = 'Make a mesh';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.T1FileLocationLabel = uilabel(app.UIFigure);
            app.T1FileLocationLabel.Text = 'T1 File Location';
            app.MRILoadButton = uibutton(app.UIFigure, 'push');
            app.MRILoadButton.ButtonPushedFcn = createCallbackFcn(app, @MRILoadButtonPushed, true);
            app.MRILoadButton.Text = 'MRI Load';
            app.progressGaugeLabel = uilabel(app.UIFigure);
            app.progressGaugeLabel.Text = 'progress';
            app.progressGauge = uigauge(app.UIFigure, 'linear');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MRIviewTab = uitab(app.TabGroup);
            app.MRIviewTab.Title = 'MRI view';
            app.MRI_UIAxes1 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes1.XTick = [];
            app.MRI_UIAxes1.YTick = [];
            app.MRI_UIAxes1.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes1ButtonDown, true);
            app.MRI_UIAxes2 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes2.XTick = [];
            app.MRI_UIAxes2.YTick = [];
            app.MRI_UIAxes2.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes2ButtonDown, true);
            app.MRI_UIAxes3 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes3.XTick = [];
            app.MRI_UIAxes3.YTick = [];
            app.MRI_UIAxes3.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes3ButtonDown, true);
            app.OptimizedT1Tab = uitab(app.TabGroup);
            app.OptimizedT1Tab.Title = 'Optimized T1';
            app.T1tap_UIAxes = uiaxes(app.OptimizedT1Tab);
            app.T1tap_UIAxes.XTick = [];
            app.T1tap_UIAxes.YTick = [];
            app.T1tap_UIAxes.ZTick = [];
            app.T1tap_UIAxes.ButtonDownFcn = createCallbackFcn(app, @T1tap_UIAxesButtonDown, true);
            app.OptimizationmatrixButton = uibutton(app.UIFigure, 'push');
            app.OptimizationmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizationmatrixButtonPushed, true);
            app.OptimizationmatrixButton.Text = {'Optimization matrix'; ''};
            app.TIoptimizationButton = uibutton(app.UIFigure, 'push');
            app.TIoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @TIoptimizationButtonPushed, true);
            app.TIoptimizationButton.Text = 'TI optimization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1optimizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end